classdef instruments < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Toolbar            matlab.ui.container.Toolbar
        PushTool           matlab.ui.container.toolbar.PushTool
        PushTool2          matlab.ui.container.toolbar.PushTool
        OscilloscopePanel  matlab.ui.container.Panel
        AcquireButton      matlab.ui.control.StateButton
        OscStatusLamp      matlab.ui.control.Lamp
        StatusLampLabel    matlab.ui.control.Label
        ScopeIDN           matlab.ui.control.TextArea
        ScopeAxes          matlab.ui.control.UIAxes
        ArbitraryWaveformGeneratorPanel  matlab.ui.container.Panel
        AWGOutputButton    matlab.ui.control.StateButton
        AWGIDN             matlab.ui.control.TextArea
        AWGFreqSet         matlab.ui.control.Knob
        FreqLabel          matlab.ui.control.Label
        AWGFreqField       matlab.ui.control.NumericEditField
        UIAxes             matlab.ui.control.UIAxes
        ConnIndicator      matlab.ui.control.Lamp
        ConnectButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        scope   % Scope class instance
        awg     % AWG class instance
        scopeTimer % Timer to refresh scope plot
    end
    
    methods (Access = private)
        
        function refreshScope(app, src, event)
            if app.OscStatusLamp.Color(2) == 1.0
                app.OscStatusLamp.Color = [0 0 0];
            else
                app.OscStatusLamp.Color = [0 1 0];
            end
            wf = app.scope.getWaveform(2);
            waveform = wf.split(",");
            waveform = str2double(waveform);
            plot(app.ScopeAxes, waveform);
            src;
            event;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function connect(app, event)
            app.scope = scopeClass;
            app.scope = app.scope.init("TCPIP0::192.168.158.14::hislip0::INSTR");
            
            %app.awg = awgClass;
            %app.awg = app.awg.init("TCPIP0::192.168.158.115::inst0::INSTR");
            
            app.ScopeIDN.Value = app.scope.idn;
            %app.AWGIDN.Value = app.awg.idn;
            app.ConnIndicator.Color = [0 1 0];
            
            
            %wf = app.scope.getWaveform(2);
            %waveform = wf.split(",");
            %waveform = str2double(waveform);
            %plot(app.ScopeAxes, waveform);
            
            app.scopeTimer = timer('Period',1, ...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.scopeTimer.TimerFcn = {@app.refreshScope};
            
            app.AcquireButton.Enable = true;
            
            
        end

        % Value changed function: AWGFreqSet
        function AWGFreqSetValueChanged(app, event)
            value = app.AWGFreqSet.Value;
            
            app.AWGFreqField.Value = value;
            
            app.awg.outpSine(int2str(value), "0.3 VPP");
        end

        % Value changed function: AWGFreqField
        function AWGFreqFieldValueChanged(app, event)
            value = app.AWGFreqField.Value;
            
            app.AWGFreqSet.Value = value;
            
            app.awg.outpSine(int2str(value), "0.3 VPP");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.awg.setOutp(0);
            
            delete(app)
        end

        % Value changed function: AWGOutputButton
        function AWGOutputButtonValueChanged(app, event)
            value = app.AWGOutputButton.Value;
            app.awg.setOutp(value);
            if value == 1
                app.AWGOutputButton.BackgroundColor = [0.1 1.0 0.1];
            else
                app.AWGOutputButton.BackgroundColor = [1.0 0.1 0.1];
            end
        end

        % Value changed function: AcquireButton
        function AcquireButtonValueChanged(app, event)
            value = app.AcquireButton.Value;
            
            if value == 1
                app.scopeTimer.start();
            else
                app.scopeTimer.stop();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @connect, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnIndicator = uilamp(app.UIFigure);
            app.ArbitraryWaveformGeneratorPanel = uipanel(app.UIFigure);
            app.ArbitraryWaveformGeneratorPanel.Title = 'Arbitrary Waveform Generator';
            app.UIAxes = uiaxes(app.ArbitraryWaveformGeneratorPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AWGFreqField = uieditfield(app.ArbitraryWaveformGeneratorPanel, 'numeric');
            app.AWGFreqField.Limits = [10 5000];
            app.AWGFreqField.ValueChangedFcn = createCallbackFcn(app, @AWGFreqFieldValueChanged, true);
            app.AWGFreqField.Value = 1000;
            app.FreqLabel = uilabel(app.ArbitraryWaveformGeneratorPanel);
            app.FreqLabel.Text = 'Freq';
            app.AWGFreqSet = uiknob(app.ArbitraryWaveformGeneratorPanel, 'continuous');
            app.AWGFreqSet.Limits = [10 5000];
            app.AWGFreqSet.MajorTicks = [10 1000 2000 3000 4000 5000];
            app.AWGFreqSet.ValueChangedFcn = createCallbackFcn(app, @AWGFreqSetValueChanged, true);
            app.AWGFreqSet.Value = 1000;
            app.AWGIDN = uitextarea(app.ArbitraryWaveformGeneratorPanel);
            app.AWGOutputButton = uibutton(app.ArbitraryWaveformGeneratorPanel, 'state');
            app.AWGOutputButton.ValueChangedFcn = createCallbackFcn(app, @AWGOutputButtonValueChanged, true);
            app.AWGOutputButton.Text = 'Output';
            app.OscilloscopePanel = uipanel(app.UIFigure);
            app.OscilloscopePanel.Title = 'Oscilloscope';
            app.ScopeAxes = uiaxes(app.OscilloscopePanel);
            title(app.ScopeAxes, 'Scope')
            xlabel(app.ScopeAxes, 'X')
            ylabel(app.ScopeAxes, 'Y')
            zlabel(app.ScopeAxes, 'Z')
            app.ScopeAxes.YTick = [-0.5 -0.25 0 0.25 0.5];
            app.ScopeAxes.YTickLabel = {'-0.5'; '-0.25'; '0'; '0.25'; '0.5'};
            app.ScopeIDN = uitextarea(app.OscilloscopePanel);
            app.StatusLampLabel = uilabel(app.OscilloscopePanel);
            app.StatusLampLabel.Text = 'Status';
            app.OscStatusLamp = uilamp(app.OscilloscopePanel);
            app.AcquireButton = uibutton(app.OscilloscopePanel, 'state');
            app.AcquireButton.ValueChangedFcn = createCallbackFcn(app, @AcquireButtonValueChanged, true);
            app.AcquireButton.Enable = 'off';
            app.AcquireButton.Text = 'Acquire';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = instruments

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end